classdef problem_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LoadButton            matlab.ui.control.Button
        ModelEditField        matlab.ui.control.EditField
        ModelEditFieldLabel   matlab.ui.control.Label
        ClassifyButton        matlab.ui.control.Button
        FruitsEditField       matlab.ui.control.EditField
        FruitsEditFieldLabel  matlab.ui.control.Label
        ResultLabel           matlab.ui.control.Label
        ChooseButton          matlab.ui.control.Button
        PhotoEditField        matlab.ui.control.EditField
        PhotoEditFieldLabel   matlab.ui.control.Label
        SegmentationLabel     matlab.ui.control.Label
        SegmentationImage     matlab.ui.control.Image
        MaskLabel             matlab.ui.control.Label
        MaskImage             matlab.ui.control.Image
        InputLabel            matlab.ui.control.Label
        InputImage            matlab.ui.control.Image
    end

    
    properties (Access = private)
        Mdl                % trained model (fitcecoc)
        mu                 % feature mean (for normalization)
        sigma              % feature std (for normalization)
        classNames         % cell array of class names
        modelLoaded = false
        currentImage       % original image (matrix)
        currentCrop        % cropped segmented object (matrix)
        currentMask        % binary mask (matrix)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
        % function onSearchButtonPushed(app)
            try
                [fn, fp] = uigetfile({'*.jpg;*.png;*.jpeg'}, 'Pilih gambar buah');
                if isequal(fn,0)
                    return;
                end
                imgPath = fullfile(fp, fn);
                app.PhotoEditField.Value = imgPath;

                % read image
                I = imread(imgPath);
                app.currentImage = I;

                % show input image (uiimage supports path or data)
                try
                    app.InputImage.ImageSource = imgPath;
                catch
                    % fallback: write temp file
                    tmpin = [tempname() '.png'];
                    imwrite(I, tmpin);
                    app.InputImage.ImageSource = tmpin;
                end

                % Attempt segmentation (requires segmentFruit.m on path)
                if exist('segmentFruit','file') == 2
                    try
                        [cropImg, mask] = segmentFruit(I);
                        app.currentCrop = cropImg;
                        app.currentMask = mask;
                    catch segErr
                        uialert(app.UIFigure, sprintf('Gagal segmentasi: %s\nMenggunakan gambar asli.', segErr.message), 'Segmentasi error');
                        app.currentCrop = I;
                        app.currentMask = [];
                    end
                else
                    uialert(app.UIFigure, 'segmentFruit.m tidak ditemukan di path. Gunakan gambar utuh.', 'Fungsi segment tidak ada');
                    app.currentCrop = I;
                    app.currentMask = [];
                end

                % show mask and crop (use temp files for uiimage)
                % Mask (binary) -> convert to RGB for saving
                if ~isempty(app.currentMask)
                    tmpmask = [tempname() '.png'];
                    imwrite(im2uint8(app.currentMask), tmpmask);
                    app.MaskImage.ImageSource = tmpmask;
                else
                    app.MaskImage.ImageSource = ''; % clear
                end

                if ~isempty(app.currentCrop)
                    tmpcrop = [tempname() '.png'];
                    % ensure uint8 RGB
                    C = app.currentCrop;
                    if ~isa(C,'uint8')
                        C = im2uint8(C);
                    end
                    imwrite(C, tmpcrop);
                    app.SegmentationImage.ImageSource = tmpcrop;
                else
                    app.SegmentationImage.ImageSource = '';
                end

                % enable classify button if model loaded (or still allow attempt)
                app.ClassifyButton.Enable = 'on';
            catch ME
                uialert(app.UIFigure, sprintf('Error saat memuat gambar:\n%s', ME.message), 'Error');
            end
        % end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            app.FruitsEditField.Value = '';
            % loading ui
            d = uiprogressdlg(app.UIFigure,...
                'Title','Classifying',...
                'Message','Extracting features and classifying image...', ...
                'Indeterminate','on');
        
            oldPointer = app.UIFigure.Pointer;
            app.UIFigure.Pointer = 'watch';
            app.LoadButton.Enable = 'off';
            app.ChooseButton.Enable = 'off';
            app.ClassifyButton.Enable = 'off';
            drawnow;
        
            try
                % check model loaded
                if ~app.modelLoaded
                    uialert(app.UIFigure, 'Model belum dimuat. Pastikan file model ada dan app di-load kembali.', 'Model belum ada');
                    return;
                end
        
                % get image to classify (prefer segmented crop)
                if ~isempty(app.currentCrop)
                    IforFeat = app.currentCrop;
                elseif ~isempty(app.currentImage)
                    IforFeat = app.currentImage;
                else
                    uialert(app.UIFigure, 'Tidak ada gambar untuk diklasifikasi. Tekan Choose terlebih dahulu.', 'No input');
                    return;
                end
        
                % extract features (requires extractFeatures.m)
                if exist('extractFeatures','file') ~= 2
                    uialert(app.UIFigure, 'Fungsi extractFeatures.m tidak ditemukan di path.', 'Fungsi tidak ada');
                    return;
                end
        
                % perform extraction + normalize + predict
                feat = extractFeatures(IforFeat); % row vector
                Xn = (feat - app.mu) ./ app.sigma;
                [lab, ~] = predict(app.Mdl, Xn);
        
                % determine label index & name (handle numeric or categorical/string)
                if isnumeric(lab)
                    idx = double(lab);
                elseif ischar(lab) || isstring(lab)
                    labstr = char(lab);
                    idx = find(strcmp(app.classNames, labstr), 1);
                    if isempty(idx)
                        % try matching Mdl.ClassNames
                        try
                            idx = find(strcmp(cellstr(string(app.Mdl.ClassNames)), labstr), 1);
                        catch
                            idx = [];
                        end
                    end
                elseif iscategorical(lab)
                    labstr = char(lab);
                    idx = find(strcmp(app.classNames, labstr), 1);
                else
                    idx = [];
                end
        
                % fallback mapping if needed
                if isempty(idx)
                    if isnumeric(lab) && lab>=1 && lab<=numel(app.classNames)
                        idx = double(lab);
                    else
                        idx = 1; % default fallback
                    end
                end
        
                predictedName = app.classNames{idx};
        
                % display result (no confidence)
                app.FruitsEditField.Value = predictedName;
        
            catch ME
                uialert(app.UIFigure, sprintf('Error saat klasifikasi:\n%s', ME.message), 'Classification error');
            end
        
            % close dialog & restore UI
            try
                close(d);
            catch
            end
            app.UIFigure.Pointer = oldPointer;
            app.LoadButton.Enable = 'on';
            app.ChooseButton.Enable = 'on';
            % keep classify enabled if model loaded and image available
            if app.modelLoaded && (~isempty(app.currentCrop) || ~isempty(app.currentImage))
                app.ClassifyButton.Enable = 'on';
            else
                app.ClassifyButton.Enable = 'off';
            end
            drawnow;

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fn, fp] = uigetfile('*.mat','Select model file');
            if isequal(fn,0)
                return;
            end
            modelPath = fullfile(fp, fn);
            app.ModelEditField.Value = modelPath;
        
            % loading info
            d = uiprogressdlg(app.UIFigure,...
                'Title','Loading Model',...
                'Message','Please wait, loading model...', ...
                'Indeterminate','on');
        
            % set busy cursor and disable interactive buttons
            oldPointer = app.UIFigure.Pointer;
            app.UIFigure.Pointer = 'watch';
            app.LoadButton.Enable = 'off';
            app.ChooseButton.Enable = 'off';
            app.ClassifyButton.Enable = 'off';
            drawnow;
        
            try
                S = load(modelPath, 'Mdl', 'mu', 'sigma', 'classNames', 'calibrated');
                app.Mdl = S.Mdl;
                app.mu = S.mu;
                app.sigma = S.sigma;
                app.classNames = S.classNames;
                if isfield(S,'calibrated')
                    app.UserData.modelCalibrated = S.calibrated;
                end
                app.modelLoaded = true;
                uialert(app.UIFigure, 'Model berhasil dimuat.','Sukses');
            catch ME
                app.modelLoaded = false;
                uialert(app.UIFigure, sprintf('Gagal memuat model: %s', ME.message), 'Error');
            end
        
            % close dialog and restore UI
            try
                close(d);
            catch
            end
            app.UIFigure.Pointer = oldPointer;
            app.LoadButton.Enable = 'on';
            app.ChooseButton.Enable = 'on';
            % enable classify only if model loaded and an image is present
            if app.modelLoaded && (~isempty(app.currentCrop) || ~isempty(app.currentImage))
                app.ClassifyButton.Enable = 'on';
            else
                app.ClassifyButton.Enable = 'off';
            end
            drawnow;
        % end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputImage = uiimage(app.UIFigure);
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.MaskImage = uiimage(app.UIFigure);
            app.MaskLabel = uilabel(app.UIFigure);
            app.MaskLabel.Text = 'Mask';
            app.SegmentationImage = uiimage(app.UIFigure);
            app.SegmentationLabel = uilabel(app.UIFigure);
            app.SegmentationLabel.Text = 'Segmentation';
            app.PhotoEditFieldLabel = uilabel(app.UIFigure);
            app.PhotoEditFieldLabel.Text = 'Photo';
            app.PhotoEditField = uieditfield(app.UIFigure, 'text');
            app.ChooseButton = uibutton(app.UIFigure, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.FruitsEditFieldLabel = uilabel(app.UIFigure);
            app.FruitsEditFieldLabel.Text = 'Fruits';
            app.FruitsEditField = uieditfield(app.UIFigure, 'text');
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.ModelEditFieldLabel = uilabel(app.UIFigure);
            app.ModelEditFieldLabel.Text = 'Model';
            app.ModelEditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = problem_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end