classdef platmobil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        label_status_2                  matlab.ui.control.Label
        label_status                    matlab.ui.control.Label
        label_accuracy_2                matlab.ui.control.Label
        label_accuracy                  matlab.ui.control.Label
        label_title                     matlab.ui.control.Label
        edit_result                     matlab.ui.control.EditField
        HasilDeteksiPlatEditFieldLabel  matlab.ui.control.Label
        btn_process                     matlab.ui.control.Button
        btn_load                        matlab.ui.control.Button
        axes_segmented                  matlab.ui.control.UIAxes
        axes_processed                  matlab.ui.control.UIAxes
        axes_input                      matlab.ui.control.UIAxes
    end

properties (Access = private)
    ImagePath
end

methods (Access = private)
    function [processed_img, binary_img] = preprocessImage(app, img)
        if size(img, 3) == 3
            gray_img = rgb2gray(img);
        else
            gray_img = img;
        end
        
        gray_img = imresize(gray_img, [200, NaN]);
        
        filtered_img = medfilt2(gray_img, [3 3]);
        
        enhanced_img = imadjust(filtered_img);
        
        level = graythresh(enhanced_img);
        binary_img = imbinarize(enhanced_img, level);
        
        if mean(binary_img(:)) > 0.5
            binary_img = ~binary_img;
        end
        
        se = strel('rectangle', [2 2]);
        binary_img = imclose(binary_img, se);
        binary_img = imopen(binary_img, se);
        
        binary_img = bwareaopen(binary_img, 30);
        
        processed_img = binary_img;
    end

    function [characters, char_imgs] = segmentCharacters(app, binary_img)
        cc = bwconncomp(binary_img);
        stats = regionprops(cc, 'BoundingBox', 'Area', 'Image');
        
        if isempty(stats)
            characters = {};
            char_imgs = {};
            return;
        end
        
        valid_chars = [];
        
        areas = [stats.Area];
        heights = arrayfun(@(s) s.BoundingBox(4), stats);
        median_height = median(heights);
        
        for i = 1:length(stats)
            bbox = stats(i).BoundingBox;
            area = stats(i).Area;
            
            width = bbox(3);
            height = bbox(4);
            
            if height < 10 || width < 5
                continue;
            end
            
            aspect_ratio = height / width;
            
            if area > 50 && aspect_ratio > 0.3 && aspect_ratio < 6
                if height > median_height * 0.4 && height < median_height * 2.5
                    valid_chars = [valid_chars; i];
                end
            end
        end
        
        if isempty(valid_chars)
            characters = {};
            char_imgs = {};
            return;
        end
        
        boxes = vertcat(stats(valid_chars).BoundingBox);
        [~, sort_idx] = sort(boxes(:, 1));
        valid_chars = valid_chars(sort_idx);
        
        characters = cell(length(valid_chars), 1);
        char_imgs = cell(length(valid_chars), 1);
        
        for i = 1:length(valid_chars)
            char_img = stats(valid_chars(i)).Image;
            
            char_img = padarray(char_img, [3 3], 0, 'both');
            
            char_img = imresize(char_img, [42 24]);
            
            characters{i} = char_img;
            char_imgs{i} = char_img;
        end
    end

    function result = recognizeCharacters(app, characters)
        if isempty(characters)
            result = 'Tidak terdeteksi';
            return;
        end
        
        templates = app.createTemplates();
        
        result = '';
        for i = 1:length(characters)
            char_img = characters{i};
            
            recognized_char = app.matchTemplate(char_img, templates);
            result = [result, recognized_char];
        end
        
        result = app.formatPlateNumber(result);
    end

    function char = matchTemplate(app, char_img, templates)

    char_img = double(char_img);
    char_img = char_img / max(char_img(:));
    
    max_score = -inf;
    recognized = '?';
    
    keys = fieldnames(templates);
    for i = 1:length(keys)
        template = templates.(keys{i});

        template_resized = imresize(template, size(char_img));
        template_double = double(template_resized);
        template_double = template_double / max(template_double(:));

        score = normxcorr2(template_double, char_img);
        score = max(score(:));
        
        if score > max_score
            max_score = score;
            recognized = keys{i};
        end
    end
    
    if max_score < 0.5
        char = '?';
    elseif length(recognized) > 1 && recognized(1) == 'c'
        char = recognized(2:end);
    else
        char = recognized;
    end
end

function templates = createTemplates(app)
    templates = struct();
    
    % Instead of 9x8, create templates at 42x24 to match your segmented characters
    templates.c0 = app.create_template_0();
    templates.c1 = app.create_template_1();
    templates.c2 = app.create_template_2();
    templates.c3 = app.create_template_3();
    templates.c4 = app.create_template_4();
    templates.c5 = app.create_template_5();
    templates.c6 = app.create_template_6();
    templates.c7 = app.create_template_7();
    templates.c8 = app.create_template_8();
    templates.c9 = app.create_template_9();
    
    templates.cA = app.create_template_A();
    templates.cB = app.create_template_B();
    templates.cC = app.create_template_C();
    templates.cD = app.create_template_D();
    templates.cE = app.create_template_E();
    templates.cF = app.create_template_F();
    templates.cG = app.create_template_G();
    templates.cH = app.create_template_H();
    templates.cJ = app.create_template_J();
    templates.cK = app.create_template_K();
    templates.cL = app.create_template_L();
    templates.cM = app.create_template_M();
    templates.cN = app.create_template_N();
    templates.cP = app.create_template_P();
    templates.cR = app.create_template_R();
    templates.cS = app.create_template_S();
    templates.cT = app.create_template_T();
    templates.cU = app.create_template_U();
    templates.cW = app.create_template_W();
    templates.cZ = app.create_template_Z();
end

% NEW TEMPLATES - Size 42x24 (height x width)
function t = create_template_0(app)
    t = logical(zeros(42, 24));
    t(6:37, 8:17) = true;
    t(8:35, 6:8) = true;
    t(8:35, 17:19) = true;
    t(6:10, 8:17) = true;
    t(33:37, 8:17) = true;
end

function t = create_template_1(app)
    t = logical(zeros(42, 24));
    t(6:37, 11:13) = true;
    t(6:10, 9:11) = true;
    t(35:37, 7:19) = true;
end

function t = create_template_2(app)
    t = logical(zeros(42, 24));
    t(6:12, 8:17) = true;
    t(8:10, 6:8) = true;
    t(8:10, 17:19) = true;
    t(12:18, 15:19) = true;
    t(18:24, 11:15) = true;
    t(24:30, 7:11) = true;
    t(30:35, 6:8) = true;
    t(35:37, 6:19) = true;
end

function t = create_template_3(app)
    t = logical(zeros(42, 24));
    t(6:12, 8:17) = true;
    t(8:10, 17:19) = true;
    t(18:24, 10:17) = true;
    t(20:22, 17:19) = true;
    t(31:37, 8:17) = true;
    t(33:35, 17:19) = true;
end

function t = create_template_4(app)
    t = logical(zeros(42, 24));
    t(6:25, 14:16) = true;
    t(10:14, 10:14) = true;
    t(14:18, 7:10) = true;
    t(18:22, 6:7) = true;
    t(20:24, 6:19) = true;
    t(24:37, 14:16) = true;
end

function t = create_template_5(app)
    t = logical(zeros(42, 24));
    t(6:8, 6:19) = true;
    t(8:20, 6:8) = true;
    t(18:24, 8:17) = true;
    t(24:32, 17:19) = true;
    t(30:37, 8:17) = true;
    t(33:35, 6:8) = true;
end

function t = create_template_6(app)
    t = logical(zeros(42, 24));
    t(6:12, 10:17) = true;
    t(12:24, 6:8) = true;
    t(22:28, 8:17) = true;
    t(26:35, 17:19) = true;
    t(30:37, 8:17) = true;
    t(33:35, 6:8) = true;
end

function t = create_template_7(app)
    t = logical(zeros(42, 24));
    t(6:8, 6:19) = true;
    t(8:12, 17:19) = true;
    t(12:16, 15:17) = true;
    t(16:20, 13:15) = true;
    t(20:37, 11:13) = true;
end

function t = create_template_8(app)
    t = logical(zeros(42, 24));
    t(6:12, 8:17) = true;
    t(8:10, 6:8) = true;
    t(8:10, 17:19) = true;
    t(18:24, 8:17) = true;
    t(20:22, 6:8) = true;
    t(20:22, 17:19) = true;
    t(31:37, 8:17) = true;
    t(33:35, 6:8) = true;
    t(33:35, 17:19) = true;
end

function t = create_template_9(app)
    t = logical(zeros(42, 24));
    t(6:12, 8:17) = true;
    t(8:10, 6:8) = true;
    t(8:16, 17:19) = true;
    t(14:20, 8:17) = true;
    t(20:30, 15:17) = true;
    t(28:37, 8:15) = true;
end

function t = create_template_A(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:37, 17:19) = true;
    t(6:12, 8:17) = true;
    t(18:24, 8:17) = true;
end

function t = create_template_B(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:12, 8:17) = true;
    t(10:14, 17:19) = true;
    t(18:24, 8:17) = true;
    t(22:26, 17:19) = true;
    t(31:37, 8:17) = true;
    t(33:35, 17:19) = true;
end

function t = create_template_C(app)
    t = logical(zeros(42, 24));
    t(6:12, 9:17) = true;
    t(8:10, 7:9) = true;
    t(12:31, 6:8) = true;
    t(31:37, 9:17) = true;
    t(33:35, 7:9) = true;
end

function t = create_template_D(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:12, 8:17) = true;
    t(10:33, 17:19) = true;
    t(31:37, 8:17) = true;
end

function t = create_template_E(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:10, 8:19) = true;
    t(19:23, 8:16) = true;
    t(33:37, 8:19) = true;
end

function t = create_template_F(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:10, 8:19) = true;
    t(19:23, 8:16) = true;
end

function t = create_template_G(app)
    t = logical(zeros(42, 24));
    t(6:12, 9:17) = true;
    t(8:10, 7:9) = true;
    t(12:31, 6:8) = true;
    t(22:30, 14:19) = true;
    t(28:35, 17:19) = true;
    t(31:37, 9:17) = true;
end

function t = create_template_H(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:37, 17:19) = true;
    t(19:23, 8:17) = true;
end

function t = create_template_J(app)
    t = logical(zeros(42, 24));
    t(6:30, 15:17) = true;
    t(28:35, 6:8) = true;
    t(31:37, 8:15) = true;
end

function t = create_template_K(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:14, 14:19) = true;
    t(14:22, 11:14) = true;
    t(22:37, 14:19) = true;
end

function t = create_template_L(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(33:37, 8:19) = true;
end

function t = create_template_M(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:37, 17:19) = true;
    t(6:18, 10:12) = true;
    t(6:18, 13:15) = true;
end

function t = create_template_N(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:37, 17:19) = true;
    t(10:22, 10:12) = true;
    t(22:34, 13:15) = true;
end

function t = create_template_P(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:12, 8:17) = true;
    t(10:14, 17:19) = true;
    t(18:24, 8:17) = true;
end

function t = create_template_R(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:12, 8:17) = true;
    t(10:14, 17:19) = true;
    t(18:24, 8:17) = true;
    t(22:37, 14:19) = true;
end

function t = create_template_S(app)
    t = logical(zeros(42, 24));
    t(6:12, 9:17) = true;
    t(8:10, 7:9) = true;
    t(10:18, 6:8) = true;
    t(16:26, 8:17) = true;
    t(24:33, 17:19) = true;
    t(31:37, 8:16) = true;
    t(33:35, 16:18) = true;
end

function t = create_template_T(app)
    t = logical(zeros(42, 24));
    t(6:10, 6:19) = true;
    t(10:37, 11:14) = true;
end

function t = create_template_U(app)
    t = logical(zeros(42, 24));
    t(6:33, 6:8) = true;
    t(6:33, 17:19) = true;
    t(31:37, 8:17) = true;
end

function t = create_template_W(app)
    t = logical(zeros(42, 24));
    t(6:37, 6:8) = true;
    t(6:37, 17:19) = true;
    t(20:37, 10:12) = true;
    t(20:37, 13:15) = true;
end

function t = create_template_Z(app)
    t = logical(zeros(42, 24));
    t(6:10, 6:19) = true;
    t(10:16, 15:19) = true;
    t(16:22, 11:15) = true;
    t(22:28, 7:11) = true;
    t(28:33, 6:7) = true;
    t(33:37, 6:19) = true;
end

    function formatted = formatPlateNumber(app, raw_text)
        if isempty(raw_text)
            formatted = raw_text;
            return;
        end
        
        letters = '';
        numbers = '';
        last_letters = '';
        
        state = 1;
        
        for i = 1:length(raw_text)
            c = raw_text(i);
            if isstrprop(c, 'alpha')
                if state == 1
                    letters = [letters, c];
                else
                    last_letters = [last_letters, c];
                    state = 3;
                end
            elseif isstrprop(c, 'digit')
                numbers = [numbers, c];
                if state == 1
                    state = 2;
                end
            end
        end
        
        formatted = strtrim([letters, ' ', numbers, ' ', last_letters]);
    end

    function montage_img = createMontage(app, char_imgs)
        if isempty(char_imgs)
            montage_img = zeros(42, 24);
            return;
        end
        
        n = length(char_imgs);
        cols = ceil(sqrt(n));
        rows = ceil(n / cols);
        
        h = 42;
        w = 24;
        
        montage_img = zeros(h * rows, w * cols);
        
        for i = 1:n
            row = ceil(i / cols);
            col = mod(i - 1, cols) + 1;
            
            r_start = (row - 1) * h + 1;
            r_end = row * h;
            c_start = (col - 1) * w + 1;
            c_end = col * w;
            
            montage_img(r_start:r_end, c_start:c_end) = char_imgs{i};
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_load
        function btn_loadButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'});
            if file ~= 0
                app.ImagePath = fullfile(path, file);
                img = imread(app.ImagePath);
                imshow(img, 'Parent', app.axes_input);
                title(app.axes_input, 'Citra Input');
                app.btn_process.Enable = 'on';
        
                %reset hasil sebelumnya
                cla(app.axes_processed);
                cla(app.axes_segmented);
                app.edit_result.Value = '';
                app.label_status.Text = 'Citra berhasil dimuat. Klik "Process" untuk memulai.';
            end
        end

        % Button pushed function: btn_process
        function btn_processButtonPushed(app, event)
    try
        img = imread(app.ImagePath);
        app.label_status.Text = 'Memproses...';
        drawnow;
        
        % Preprocessing
        [processed_img, binary_img] = app.preprocessImage(img);
        imshow(processed_img, 'Parent', app.axes_processed);
        title(app.axes_processed, 'Citra Terproses');
        
        % Segmentasi karakter
        [characters, char_imgs] = app.segmentCharacters(binary_img);
        
        % Tampilkan karakter tersegmentasi
        if ~isempty(char_imgs)
            montage_img = app.createMontage(char_imgs);
            imshow(montage_img, 'Parent', app.axes_segmented);
            title(app.axes_segmented, sprintf('%d Karakter Terdeteksi', length(characters)));
        end
        
        % Recognisi karakter
        result = app.recognizeCharacters(characters);
        app.edit_result.Value = result;
        app.label_status.Text = 'Pengenalan selesai';
        
    catch ME
        app.label_status.Text = sprintf('Error: %s', ME.message);
        uialert(app.UIFigure, ME.message, 'Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_input = uiaxes(app.UIFigure);
            title(app.axes_input, 'Citra Input')
            xlabel(app.axes_input, 'X')
            ylabel(app.axes_input, 'Y')
            zlabel(app.axes_input, 'Z')
            app.axes_processed = uiaxes(app.UIFigure);
            title(app.axes_processed, 'Citra Preprocessing')
            xlabel(app.axes_processed, 'X')
            ylabel(app.axes_processed, 'Y')
            zlabel(app.axes_processed, 'Z')
            app.axes_segmented = uiaxes(app.UIFigure);
            title(app.axes_segmented, 'Citra Segmentasi')
            xlabel(app.axes_segmented, 'X')
            ylabel(app.axes_segmented, 'Y')
            zlabel(app.axes_segmented, 'Z')
            app.btn_load = uibutton(app.UIFigure, 'push');
            app.btn_load.ButtonPushedFcn = createCallbackFcn(app, @btn_loadButtonPushed, true);
            app.btn_load.Text = 'Load Gambar';
            app.btn_process = uibutton(app.UIFigure, 'push');
            app.btn_process.ButtonPushedFcn = createCallbackFcn(app, @btn_processButtonPushed, true);
            app.btn_process.Text = 'Proses Gambar';
            app.HasilDeteksiPlatEditFieldLabel = uilabel(app.UIFigure);
            app.HasilDeteksiPlatEditFieldLabel.Text = 'Hasil Deteksi Plat';
            app.edit_result = uieditfield(app.UIFigure, 'text');
            app.edit_result.Editable = 'off';
            app.label_title = uilabel(app.UIFigure);
            app.label_title.Text = 'Plat Mobil';
            app.label_accuracy = uilabel(app.UIFigure);
            app.label_accuracy.Text = '0%';
            app.label_accuracy_2 = uilabel(app.UIFigure);
            app.label_accuracy_2.Text = 'Akurasi:';
            app.label_status = uilabel(app.UIFigure);
            app.label_status.Text = '';
            app.label_status_2 = uilabel(app.UIFigure);
            app.label_status_2.Text = 'Status:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = platmobil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end