classdef platmobil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        label_status_2                  matlab.ui.control.Label
        label_status                    matlab.ui.control.Label
        label_accuracy_2                matlab.ui.control.Label
        label_accuracy                  matlab.ui.control.Label
        label_title                     matlab.ui.control.Label
        edit_result                     matlab.ui.control.EditField
        HasilDeteksiPlatEditFieldLabel  matlab.ui.control.Label
        btn_process                     matlab.ui.control.Button
        btn_load                        matlab.ui.control.Button
        axes_segmented                  matlab.ui.control.UIAxes
        axes_processed                  matlab.ui.control.UIAxes
        axes_input                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImagePath
    end
    
methods (Access = private)
    function [processed_img, binary_img] = preprocessImage(app, img)
        if size(img, 3) == 3
            gray_img = rgb2gray(img);
        else
            gray_img = img;
        end

        gray_img = imresize(gray_img, [200, NaN]);

        filtered_img = medfilt2(gray_img, [3 3]);

        enhanced_img = imadjust(filtered_img);

        binary_img = imbinarize(enhanced_img, 'adaptive', 'Sensitivity', 0.5);

        if mean(binary_img(:)) > 0.5
            binary_img = ~binary_img;
        end

        se = strel('disk', 1);
        binary_img = imopen(binary_img, se);
        binary_img = imclose(binary_img, se);

        binary_img = bwareaopen(binary_img, 50);

        processed_img = binary_img;
    end


    function [characters, char_imgs] = segmentCharacters(app, binary_img)

        cc = bwconncomp(binary_img);
        stats = regionprops(cc, 'BoundingBox', 'Area', 'Image');

        valid_chars = [];
        char_imgs = {};
        
        for i = 1:length(stats)
            bbox = stats(i).BoundingBox;
            area = stats(i).Area;
            aspect_ratio = bbox(4) / bbox(3);

            if area > 100 && area < 5000 && aspect_ratio > 0.5 && aspect_ratio < 4
                valid_chars = [valid_chars; i];
            end
        end

        boxes = vertcat(stats(valid_chars).BoundingBox);
        [~, sort_idx] = sort(boxes(:, 1));
        valid_chars = valid_chars(sort_idx);

        characters = cell(length(valid_chars), 1);
        char_imgs = cell(length(valid_chars), 1);
        
        for i = 1:length(valid_chars)
            char_img = stats(valid_chars(i)).Image;

            char_img = imresize(char_img, [42 24]);

            char_img = bwmorph(char_img, 'thin', Inf);
            
            characters{i} = char_img;
            char_imgs{i} = char_img;
        end
    end

    function result = recognizeCharacters(app, characters)
        if isempty(characters)
            result = 'Tidak terdeteksi';
            return;
        end

        templates = app.createTemplates();
        
        result = '';
        for i = 1:length(characters)
            char_img = characters{i};

            recognized_char = app.matchTemplate(char_img, templates);
            result = [result, recognized_char];
        end

        result = app.formatPlateNumber(result);
    end

    function char = matchTemplate(app, char_img, templates)
        max_corr = -inf;
        recognized = '?';
        
        keys = fieldnames(templates);
        for i = 1:length(keys)
            template = templates.(keys{i});

            template_resized = imresize(template, size(char_img));

            corr_val = corr2(double(char_img), double(template_resized));
            
            if corr_val > max_corr
                max_corr = corr_val;
                recognized = keys{i};
            end
        end

        if length(recognized) > 1 && recognized(1) == 'c'
            char = recognized(2:end);
        else
            char = recognized;
        end
    end

    function templates = createTemplates(app)

        templates = struct();

        templates.c0 = app.create_template_0();
        templates.c1 = app.create_template_1();
        templates.c2 = app.create_template_2();
        templates.c3 = app.create_template_3();
        templates.c4 = app.create_template_4();
        templates.c5 = app.create_template_5();
        templates.c6 = app.create_template_6();
        templates.c7 = app.create_template_7();
        templates.c8 = app.create_template_8();
        templates.c9 = app.create_template_9();

        templates.cA = app.create_template_A();
        templates.cB = app.create_template_B();
        templates.cC = app.create_template_C();
        templates.cD = app.create_template_D();
        templates.cE = app.create_template_E();
        templates.cF = app.create_template_F();
        templates.cG = app.create_template_G();
        templates.cH = app.create_template_H();
        templates.cJ = app.create_template_J();
        templates.cK = app.create_template_K();
        templates.cL = app.create_template_L();
        templates.cM = app.create_template_M();
        templates.cN = app.create_template_N();
        templates.cP = app.create_template_P();
        templates.cR = app.create_template_R();
        templates.cS = app.create_template_S();
        templates.cT = app.create_template_T();
        templates.cU = app.create_template_U();
        templates.cW = app.create_template_W();
        templates.cZ = app.create_template_Z();
    end

    function t = create_template_0(app)
        t = logical([
            0 1 1 1 0;
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1;
            0 1 1 1 0
        ]);
    end

    function t = create_template_1(app)
        t = logical([
            0 0 1 0 0;
            0 1 1 0 0;
            0 0 1 0 0;
            0 0 1 0 0;
            0 0 1 0 0;
            0 1 1 1 0
        ]);
    end

    function t = create_template_2(app)
        t = logical([
            0 1 1 1 0;
            1 0 0 0 1;
            0 0 0 1 0;
            0 0 1 0 0;
            0 1 0 0 0;
            1 1 1 1 1
        ]);
    end

    function t = create_template_3(app)
        t = logical([
            1 1 1 1 0;
            0 0 0 0 1;
            0 1 1 1 0;
            0 0 0 0 1;
            0 0 0 0 1;
            1 1 1 1 0
        ]);
    end

    function t = create_template_4(app)
        t = logical([
            0 0 0 1 0;
            0 0 1 1 0;
            0 1 0 1 0;
            1 1 1 1 1;
            0 0 0 1 0;
            0 0 0 1 0
        ]);
    end

    function t = create_template_5(app)
        t = logical([
            1 1 1 1 1;
            1 0 0 0 0;
            1 1 1 1 0;
            0 0 0 0 1;
            0 0 0 0 1;
            1 1 1 1 0
        ]);
    end

    function t = create_template_6(app)
        t = logical([
            0 1 1 1 0;
            1 0 0 0 0;
            1 1 1 1 0;
            1 0 0 0 1;
            1 0 0 0 1;
            0 1 1 1 0
        ]);
    end

    function t = create_template_7(app)
        t = logical([
            1 1 1 1 1;
            0 0 0 0 1;
            0 0 0 1 0;
            0 0 1 0 0;
            0 1 0 0 0;
            0 1 0 0 0
        ]);
    end

    function t = create_template_8(app)
        t = logical([
            0 1 1 1 0;
            1 0 0 0 1;
            0 1 1 1 0;
            1 0 0 0 1;
            1 0 0 0 1;
            0 1 1 1 0
        ]);
    end

    function t = create_template_9(app)
        t = logical([
            0 1 1 1 0;
            1 0 0 0 1;
            1 0 0 0 1;
            0 1 1 1 1;
            0 0 0 0 1;
            0 1 1 1 0
        ]);
    end

    % Template untuk huruf (contoh)
    function t = create_template_A(app)
        t = logical([
            0 0 1 0 0;
            0 1 0 1 0;
            1 0 0 0 1;
            1 1 1 1 1;
            1 0 0 0 1;
            1 0 0 0 1
        ]);
    end

    function t = create_template_B(app)
        t = logical([
            1 1 1 1 0;
            1 0 0 0 1;
            1 1 1 1 0;
            1 0 0 0 1;
            1 0 0 0 1;
            1 1 1 1 0
        ]);
    end

    function t = create_template_C(app)
        t = logical([
            0 1 1 1 1;
            1 0 0 0 0;
            1 0 0 0 0;
            1 0 0 0 0;
            1 0 0 0 0;
            0 1 1 1 1
        ]);
    end

    function t = create_template_D(app)
        t = logical([
            1 1 1 1 0;
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1;
            1 1 1 1 0
        ]);
    end

    function t = create_template_E(app)
        t = logical([
            1 1 1 1 1;
            1 0 0 0 0;
            1 1 1 1 0;
            1 0 0 0 0;
            1 0 0 0 0;
            1 1 1 1 1
        ]);
    end

    function t = create_template_F(app)
        t = logical([
            1 1 1 1 1;
            1 0 0 0 0;
            1 1 1 1 0;
            1 0 0 0 0;
            1 0 0 0 0;
            1 0 0 0 0
        ]);
    end

    function t = create_template_G(app)
        t = logical([
            0 1 1 1 1;
            1 0 0 0 0;
            1 0 0 0 0;
            1 0 1 1 1;
            1 0 0 0 1;
            0 1 1 1 1
        ]);
    end

    function t = create_template_H(app)
        t = logical([
            1 0 0 0 1;
            1 0 0 0 1;
            1 1 1 1 1;
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1
        ]);
    end

    function t = create_template_J(app)
        t = logical([
            0 0 0 0 1;
            0 0 0 0 1;
            0 0 0 0 1;
            0 0 0 0 1;
            1 0 0 0 1;
            0 1 1 1 0
        ]);
    end

    function t = create_template_K(app)
        t = logical([
            1 0 0 0 1;
            1 0 0 1 0;
            1 1 1 0 0;
            1 0 0 1 0;
            1 0 0 0 1;
            1 0 0 0 1
        ]);
    end

    function t = create_template_L(app)
        t = logical([
            1 0 0 0 0;
            1 0 0 0 0;
            1 0 0 0 0;
            1 0 0 0 0;
            1 0 0 0 0;
            1 1 1 1 1
        ]);
    end

    function t = create_template_M(app)
        t = logical([
            1 0 0 0 1;
            1 1 0 1 1;
            1 0 1 0 1;
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1
        ]);
    end

    function t = create_template_N(app)
        t = logical([
            1 0 0 0 1;
            1 1 0 0 1;
            1 0 1 0 1;
            1 0 0 1 1;
            1 0 0 0 1;
            1 0 0 0 1
        ]);
    end

    function t = create_template_P(app)
        t = logical([
            1 1 1 1 0;
            1 0 0 0 1;
            1 0 0 0 1;
            1 1 1 1 0;
            1 0 0 0 0;
            1 0 0 0 0
        ]);
    end

    function t = create_template_R(app)
        t = logical([
            1 1 1 1 0;
            1 0 0 0 1;
            1 0 0 0 1;
            1 1 1 1 0;
            1 0 0 1 0;
            1 0 0 0 1
        ]);
    end

    function t = create_template_S(app)
        t = logical([
            0 1 1 1 1;
            1 0 0 0 0;
            0 1 1 1 0;
            0 0 0 0 1;
            0 0 0 0 1;
            1 1 1 1 0
        ]);
    end

    function t = create_template_T(app)
        t = logical([
            1 1 1 1 1;
            0 0 1 0 0;
            0 0 1 0 0;
            0 0 1 0 0;
            0 0 1 0 0;
            0 0 1 0 0
        ]);
    end

    function t = create_template_U(app)
        t = logical([
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1;
            0 1 1 1 0
        ]);
    end

    function t = create_template_W(app)
        t = logical([
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 0 0 1;
            1 0 1 0 1;
            1 1 0 1 1;
            1 0 0 0 1
        ]);
    end

    function t = create_template_Z(app)
        t = logical([
            1 1 1 1 1;
            0 0 0 1 0;
            0 0 1 0 0;
            0 1 0 0 0;
            1 0 0 0 0;
            1 1 1 1 1
        ]);
    end

    %% Fungsi Format Nomor Plat
    function formatted = formatPlateNumber(app, raw_text)
        % Format: B 1234 XYZ
        % Huruf di awal, angka di tengah, huruf di akhir
        
        if isempty(raw_text)
            formatted = raw_text;
            return;
        end
        
        % Pisahkan huruf dan angka
        letters = '';
        numbers = '';
        last_letters = '';
        
        state = 1; % 1=huruf awal, 2=angka, 3=huruf akhir
        
        for i = 1:length(raw_text)
            c = raw_text(i);
            if isstrprop(c, 'alpha')
                if state == 1
                    letters = [letters, c];
                else
                    last_letters = [last_letters, c];
                    state = 3;
                end
            elseif isstrprop(c, 'digit')
                numbers = [numbers, c];
                if state == 1
                    state = 2;
                end
            end
        end
        
        % Format dengan spasi
        formatted = strtrim([letters, ' ', numbers, ' ', last_letters]);
    end

    %% Fungsi Membuat Montage
    function montage_img = createMontage(app, char_imgs)
        if isempty(char_imgs)
            montage_img = zeros(42, 24);
            return;
        end
        
        n = length(char_imgs);
        cols = ceil(sqrt(n));
        rows = ceil(n / cols);
        
        h = 42;
        w = 24;
        
        montage_img = zeros(h * rows, w * cols);
        
        for i = 1:n
            row = ceil(i / cols);
            col = mod(i - 1, cols) + 1;
            
            r_start = (row - 1) * h + 1;
            r_end = row * h;
            c_start = (col - 1) * w + 1;
            c_end = col * w;
            
            montage_img(r_start:r_end, c_start:c_end) = char_imgs{i};
        end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_load
        function btn_loadButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'});
            if file ~= 0
                app.ImagePath = fullfile(path, file);
                img = imread(app.ImagePath);
                imshow(img, 'Parent', app.axes_input);
                title(app.axes_input, 'Citra Input');
                app.btn_process.Enable = 'on';
        
                %reset hasil sebelumnya
                cla(app.axes_processed);
                cla(app.axes_segmented);
                app.edit_result.Value = '';
                app.label_status.Text = 'Citra berhasil dimuat. Klik "Process" untuk memulai.';
            end
        end

        % Button pushed function: btn_process
        function btn_processButtonPushed(app, event)
    try
        img = imread(app.ImagePath);
        app.label_status.Text = 'Memproses...';
        drawnow;
        
        % Preprocessing
        [processed_img, binary_img] = app.preprocessImage(img);
        imshow(processed_img, 'Parent', app.axes_processed);
        title(app.axes_processed, 'Citra Terproses');
        
        % Segmentasi karakter
        [characters, char_imgs] = app.segmentCharacters(binary_img);
        
        % Tampilkan karakter tersegmentasi
        if ~isempty(char_imgs)
            montage_img = app.createMontage(char_imgs);
            imshow(montage_img, 'Parent', app.axes_segmented);
            title(app.axes_segmented, sprintf('%d Karakter Terdeteksi', length(characters)));
        end
        
        % Recognisi karakter
        result = app.recognizeCharacters(characters);
        app.edit_result.Value = result;
        app.label_status.Text = 'Pengenalan selesai';
        
    catch ME
        app.label_status.Text = sprintf('Error: %s', ME.message);
        uialert(app.UIFigure, ME.message, 'Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_input = uiaxes(app.UIFigure);
            title(app.axes_input, 'Citra Input')
            xlabel(app.axes_input, 'X')
            ylabel(app.axes_input, 'Y')
            zlabel(app.axes_input, 'Z')
            app.axes_processed = uiaxes(app.UIFigure);
            title(app.axes_processed, 'Citra Preprocessing')
            xlabel(app.axes_processed, 'X')
            ylabel(app.axes_processed, 'Y')
            zlabel(app.axes_processed, 'Z')
            app.axes_segmented = uiaxes(app.UIFigure);
            title(app.axes_segmented, 'Citra Segmentasi')
            xlabel(app.axes_segmented, 'X')
            ylabel(app.axes_segmented, 'Y')
            zlabel(app.axes_segmented, 'Z')
            app.btn_load = uibutton(app.UIFigure, 'push');
            app.btn_load.ButtonPushedFcn = createCallbackFcn(app, @btn_loadButtonPushed, true);
            app.btn_load.Text = 'Load Gambar';
            app.btn_process = uibutton(app.UIFigure, 'push');
            app.btn_process.ButtonPushedFcn = createCallbackFcn(app, @btn_processButtonPushed, true);
            app.btn_process.Text = 'Proses Gambar';
            app.HasilDeteksiPlatEditFieldLabel = uilabel(app.UIFigure);
            app.HasilDeteksiPlatEditFieldLabel.Text = 'Hasil Deteksi Plat';
            app.edit_result = uieditfield(app.UIFigure, 'text');
            app.edit_result.Editable = 'off';
            app.label_title = uilabel(app.UIFigure);
            app.label_title.Text = 'Plat Mobil';
            app.label_accuracy = uilabel(app.UIFigure);
            app.label_accuracy.Text = '0%';
            app.label_accuracy_2 = uilabel(app.UIFigure);
            app.label_accuracy_2.Text = 'Akurasi:';
            app.label_status = uilabel(app.UIFigure);
            app.label_status.Text = '';
            app.label_status_2 = uilabel(app.UIFigure);
            app.label_status_2.Text = 'Status:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = platmobil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end